classdef NavUtility < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OpenButton              matlab.ui.control.Button
        BaudEditFieldLabel      matlab.ui.control.Label
        BaudEditField           matlab.ui.control.NumericEditField
        FigureButton            matlab.ui.control.Button
        PortDropDownLabel       matlab.ui.control.Label
        PortDropDown            matlab.ui.control.DropDown
        Lamp                    matlab.ui.control.Lamp
        QueryButton             matlab.ui.control.Button
        BuffEditField           matlab.ui.control.NumericEditField
        TabGroup                matlab.ui.container.TabGroup
        FigureTab               matlab.ui.container.Tab
        UIAxes1                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes6                 matlab.ui.control.UIAxes
        CmdTab                  matlab.ui.container.Tab
        Send_timeButton         matlab.ui.control.Button
        Send_attButton          matlab.ui.control.Button
        Collect_StartButton     matlab.ui.control.Button
        Collect_StopButton      matlab.ui.control.Button
        CntEditField            matlab.ui.control.NumericEditField
        RestartButton           matlab.ui.control.Button
        Send_gps_llaButton      matlab.ui.control.Button
        Send_gps_xyzButton      matlab.ui.control.Button
        ParameterTab            matlab.ui.container.Tab
        SaveButton              matlab.ui.control.Button
        Read_biasButton         matlab.ui.control.Button
        GxEditFieldLabel        matlab.ui.control.Label
        Bias1EditField          matlab.ui.control.NumericEditField
        GyEditFieldLabel        matlab.ui.control.Label
        Bias2EditField          matlab.ui.control.NumericEditField
        GzEditFieldLabel        matlab.ui.control.Label
        Bias3EditField          matlab.ui.control.NumericEditField
        AxEditFieldLabel        matlab.ui.control.Label
        Bias4EditField          matlab.ui.control.NumericEditField
        AyEditFieldLabel        matlab.ui.control.Label
        Bias5EditField          matlab.ui.control.NumericEditField
        AzEditFieldLabel        matlab.ui.control.Label
        Bias6EditField          matlab.ui.control.NumericEditField
        MxEditFieldLabel        matlab.ui.control.Label
        Bias7EditField          matlab.ui.control.NumericEditField
        MyEditFieldLabel        matlab.ui.control.Label
        Bias8EditField          matlab.ui.control.NumericEditField
        MzEditFieldLabel        matlab.ui.control.Label
        Bias9EditField          matlab.ui.control.NumericEditField
        S11EditFieldLabel       matlab.ui.control.Label
        Bias10EditField         matlab.ui.control.NumericEditField
        S12EditFieldLabel       matlab.ui.control.Label
        Bias11EditField         matlab.ui.control.NumericEditField
        S13EditFieldLabel       matlab.ui.control.Label
        Bias12EditField         matlab.ui.control.NumericEditField
        S21EditFieldLabel       matlab.ui.control.Label
        Bias13EditField         matlab.ui.control.NumericEditField
        S22EditFieldLabel       matlab.ui.control.Label
        Bias14EditField         matlab.ui.control.NumericEditField
        S23EditFieldLabel       matlab.ui.control.Label
        Bias15EditField         matlab.ui.control.NumericEditField
        S31EditFieldLabel       matlab.ui.control.Label
        Bias16EditField         matlab.ui.control.NumericEditField
        S32EditFieldLabel       matlab.ui.control.Label
        Bias17EditField         matlab.ui.control.NumericEditField
        S33EditFieldLabel       matlab.ui.control.Label
        Bias18EditField         matlab.ui.control.NumericEditField
        Write_biasButton        matlab.ui.control.Button
        P1Label                 matlab.ui.control.Label
        Att1EditField           matlab.ui.control.NumericEditField
        P2Label                 matlab.ui.control.Label
        Att2EditField           matlab.ui.control.NumericEditField
        P3Label                 matlab.ui.control.Label
        Att3EditField           matlab.ui.control.NumericEditField
        P4Label                 matlab.ui.control.Label
        Att4EditField           matlab.ui.control.NumericEditField
        P5Label                 matlab.ui.control.Label
        Att5EditField           matlab.ui.control.NumericEditField
        P6Label                 matlab.ui.control.Label
        Att6EditField           matlab.ui.control.NumericEditField
        P7Label                 matlab.ui.control.Label
        Att7EditField           matlab.ui.control.NumericEditField
        Q1Label                 matlab.ui.control.Label
        Att8EditField           matlab.ui.control.NumericEditField
        Q2Label                 matlab.ui.control.Label
        Att9EditField           matlab.ui.control.NumericEditField
        Q3Label                 matlab.ui.control.Label
        Att10EditField          matlab.ui.control.NumericEditField
        Q4Label                 matlab.ui.control.Label
        Att11EditField          matlab.ui.control.NumericEditField
        Q5Label                 matlab.ui.control.Label
        Att12EditField          matlab.ui.control.NumericEditField
        Q6Label                 matlab.ui.control.Label
        Att13EditField          matlab.ui.control.NumericEditField
        R1Label                 matlab.ui.control.Label
        Att14EditField          matlab.ui.control.NumericEditField
        R2Label                 matlab.ui.control.Label
        Att15EditField          matlab.ui.control.NumericEditField
        R3Label                 matlab.ui.control.Label
        Att16EditField          matlab.ui.control.NumericEditField
        R4Label                 matlab.ui.control.Label
        Att17EditField          matlab.ui.control.NumericEditField
        Read_att_filterButton   matlab.ui.control.Button
        Write_att_filterButton  matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        Send_imuButton          matlab.ui.control.Button
        Send_magButton          matlab.ui.control.Button
    end


    properties (Access = private)
        win_size = 150;
        disp_size = 140;
        buff_size = 300;
        packet = zeros(1,100, 'uint8');
        s = 0;
        figure = 0;
        collect = 0;
    end

    methods (Access = private)
    
        function send_cmd(app, code, varargin)
            if nargin == 2
                cmd_length = 4;
            else
                cmd_length = 4 + length(varargin{1});
            end
            cmd = zeros(1,cmd_length, 'uint8');
            cmd(1) = 85; % 0x55
            cmd(2) = cmd_length;
            cmd(3) = code;
            if nargin ~= 2
                cmd(4:(cmd_length-1)) = varargin{1};
            end
            sum = uint8(0);
            for k=1:(cmd_length-1)
                sum = bitxor(sum, cmd(k));
            end
            cmd(end) = sum;
            for k=1:cmd_length
                fwrite(app.s, cmd(k), 'uint8');
            end
        end
    
        function clear_serial_buff(app)
            while 1
                nByte = app.s.BytesAvailable;
                if nByte ~= 0
                    fread(app.s, nByte, 'uint8');
                else
                    break;
                end
            end
        end
    
        function [pp, flag] = receive_packet(app, pp, Rx)
            flag = 0;
            if pp == 0
                % Detect head
                if Rx==85 % 0x55
                    pp = pp+1;
                    app.packet(pp) = Rx;
                end
            elseif pp == 1
                pp = pp+1;
                if Rx <= length(app.packet)
                    app.packet(pp) = Rx;
                else
                    pp = 0;
                end
            else
                pp = pp+1;
                app.packet(pp) = Rx;
                if pp < app.packet(2) % receive a packet
                    return;
                end
                pp = 0;
                % Check
                sum = uint8(0);
                for ki=1:(app.packet(2)-1)
                    sum = bitxor(sum, app.packet(ki));
                end
                if sum == app.packet(app.packet(2))
                    flag = 1;
                end  
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Adjust window
            screen = get(0,'ScreenSize');
            if isequal(screen(3:4), [1920 1080])
                app.UIFigure.Position = [285 190 1350 700];
            elseif isequal(screen(3:4), [1368 912])
                app.UIFigure.Position = [9 106 1350 700];
            elseif isequal(screen(3:4), [1366 768])
                app.UIFigure.Position = [8 34 1350 700];
            end
            % Close last
            if size(instrfind,2) ~= 0
                fclose(instrfind);
            end
            % Initialize axis
            axis(app.UIAxes1, [0,app.win_size, -inf,inf]);
            axis(app.UIAxes2, [0,app.win_size, -inf,inf]);
            axis(app.UIAxes3, [0,app.win_size, -inf,inf]);
            axis(app.UIAxes4, [0,app.win_size, -inf,inf]);
            axis(app.UIAxes5, [0,app.win_size, -inf,inf]);
            axis(app.UIAxes6, [0,app.win_size, -inf,inf]);
            % Read serial list
            if size(seriallist,2) ~= 0
                app.PortDropDown.Items = seriallist;
                app.PortDropDown.Value = app.PortDropDown.Items{1};
            end
            % Disable button
            app.FigureButton.Enable = 'off';
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if strcmp(app.OpenButton.Text,'Open') == 1
                app.OpenButton.Text = 'Close';
                % Turn on lamp
                app.Lamp.Color = [0 1 0];
                % Open serial
                app.s = serial(app.PortDropDown.Value, 'BaudRate',app.BaudEditField.Value, 'InputBufferSize',1024);
                fopen(app.s);
                % Enable button
                app.FigureButton.Enable = 'on';
            else
                app.OpenButton.Text = 'Open';
                % Turn off lamp
                app.Lamp.Color = [1 1 1];
                % Stop figure
                app.figure = 0;
                % Close serial
                fclose(app.s);
                % Disable button
                app.FigureButton.Enable = 'off';
                app.StopButton.Enable = 'off';
            end
        end

        % Button pushed function: FigureButton
        function FigureButtonPushed(app, event)
            % 1.Set flag
            app.figure = 1;
            % 2.Clear serial buffer
            clear_serial_buff(app);
            % 3.Enable/disable button
            app.FigureButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            % 4.Initialize control variables
            pp = 0;
            code = 0;
            % 5.Send command
            send_cmd(app, 2);
            % 6.Receive data and plot
            while 1
                if app.figure == 0
                    break;
                end
                nByte = app.s.BytesAvailable;
                if nByte == 0
                    pause(0.08);
                    continue;
                end
                app.BuffEditField.Value = nByte;
                Rx = uint8(fread(app.s, nByte, 'uint8'));
                for k=1:nByte
                    % Receive packet
                    [pp, flag] = receive_packet(app, pp, Rx(k));
                    if flag == 0
                        continue;
                    end
                    % Plot-------------------------------------------------------------------------%
                    % Rrefresh
                    if app.packet(3) == 0 %%%%%%
                        continue;
                    end
                    if app.packet(3) ~= code
                        code = app.packet(3);
                        if app.packet(3) > 100 %double
                            number = app.packet(4:9:(app.packet(2)-1));
                        else %float
                            number = app.packet(4:5:(app.packet(2)-1));
                        end
                        data = zeros(app.buff_size, length(number));
                        nData = 0;
                        head = 0;
                        tail = 0;
                        for ki = 1:6 %clear curve
                            if exist(['h',num2str(ki)],'var')
                                eval(['delete(h',num2str(ki),');']);
                            end
                        end
                    end
                    % Read a new data
                    head = head+1;
                    if head > app.buff_size
                        data(1:(app.disp_size-1),:) = data((app.buff_size-app.disp_size+2):end,:);
                        tail = 0;
                        head = app.disp_size;
                    else
                        if (head-tail) > app.disp_size                         
                            tail = tail+1;
                        end
                    end
                    if app.packet(3) > 100 %double
                        for ki=5:9:app.packet(2)
                            data(head,(ki-5)/9+1) = typecast(app.packet(ki:(ki+7)),'double');
                        end
                    else %float
                        for ki=5:5:app.packet(2)
                            data(head,(ki-5)/5+1) = double(typecast(app.packet(ki:(ki+3)),'single'));
                        end
                    end
                    nData = nData+1;
                    % Plot
                    index = (number==1);
                    if size(index,2) ~= 0
                        h1 = plot(app.UIAxes1, data((tail+1):head,index));
                    end
                    index = (number==2);
                    if size(index,2) ~= 0
                        h2 = plot(app.UIAxes2, data((tail+1):head,index));
                    end
                    index = (number==3);
                    if size(index,2) ~= 0
                        h3 = plot(app.UIAxes3, data((tail+1):head,index));
                    end
                    index = (number==4);
                    if size(index,2) ~= 0
                        h4 = plot(app.UIAxes4, data((tail+1):head,index));
                    end
                    index = (number==5);
                    if size(index,2) ~= 0
                        h5 = plot(app.UIAxes5, data((tail+1):head,index));
                    end
                    index = (number==6);
                    if size(index,2) ~= 0
                        h6 = plot(app.UIAxes6, data((tail+1):head,index));
                    end
                    %------------------------------------------------------------------------------%
                end % end k=1:nByte
            end % end while 1
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close serial
            if app.s ~= 0
                fclose(app.s);
            end
            delete(app) 
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            if size(seriallist,2) ~= 0
                app.PortDropDown.Items = seriallist;
                app.PortDropDown.Value = app.PortDropDown.Items{1};
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            send_cmd(app, 255);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.figure = 0;
            send_cmd(app, 1); %%%%
            % Disable button
            app.StopButton.Enable = 'off';
            app.FigureButton.Enable = 'on';
        end

        % Button pushed function: Send_imuButton
        function Send_imuButtonPushed(app, event)
            send_cmd(app, 2);
        end

        % Button pushed function: Send_magButton
        function Send_magButtonPushed(app, event)
            send_cmd(app, 3);
        end

        % Button pushed function: Send_timeButton
        function Send_timeButtonPushed(app, event)
            send_cmd(app, 4);
        end

        % Button pushed function: Send_attButton
        function Send_attButtonPushed(app, event)
            send_cmd(app, 5);
        end

        % Button pushed function: Collect_StartButton
        function Collect_StartButtonPushed(app, event)
            if app.figure == 0
                % 1.Set flag
                app.collect = 1;
                % 2.Clear serial buffer
                clear_serial_buff(app);
                % 3.Disable button
                app.Collect_StartButton.Enable = 'off';
                % 4.Create folder
                if ~exist('./data', 'dir')
                    mkdir('./data'); 
                end
                % 5.Create file
                current_time = clock;
                time_str = [num2str(current_time(1)),num2str(current_time(2:3),'%02d'),'_',...
                            num2str(current_time(4:5),'%02d'),num2str(floor(current_time(6)),'%02d')];
                file_imu = fopen(['./data/',time_str,'_imu.txt'], 'w+');
                file_gps = fopen(['./data/',time_str,'_gps.txt'], 'w+');
                % 6.Create data buffer
                data = zeros(1,17); %1+11+2+3
                % 7.Initialize control variables
                pp = 0;
                cnt = 0;
                % 8.Send command
                send_cmd(app, 1);
                % 9.Receive data and save
                while 1
                    if app.collect == 0
                        break;
                    end
                    nByte = app.s.BytesAvailable;
                    if nByte == 0
                        pause(0.02);
                        continue;
                    end
                    app.BuffEditField.Value = nByte;
                    Rx = uint8(fread(app.s, nByte, 'uint8'));
                    for k=1:nByte
                        % Receive packet
                        [pp, flag] = receive_packet(app, pp, Rx(k));
                        if flag == 0
                            continue;
                        end
                        % Save-------------------------------------------------------------------------%
                        cnt = cnt+1;
                        app.CntEditField.Value = cnt;
                        if app.packet(3) == 0 %code
                            for ki=4:4:(app.packet(2)-1)
                                data((ki-4)/4+1) = double(typecast(app.packet(ki:(ki+3)),'single'));
                            end
                            fprintf(file_imu, '%8d%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f\r\n',...
                                                    data(1),data(2),data(3),data(4),data(5),data(6),...
                                                    data(7),data(8),data(9),data(10),data(11),data(12));
                        elseif app.packet(3) == 1 %code
                            for ki=4:4:51
                                data((ki-4)/4+1) = double(typecast(app.packet(ki:(ki+3)),'single'));
                            end
                            data(13) = typecast(app.packet(52:59),'double');
                            data(14) = typecast(app.packet(60:67),'double');
                            data(15) = double(typecast(app.packet(68:71),'single'));
                            data(16) = double(typecast(app.packet(72:75),'single'));
                            data(17) = double(typecast(app.packet(76:79),'single'));
                            fprintf(file_imu, '%8d%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f%15.6f\r\n',...
                                                    data(1),data(2),data(3),data(4),data(5),data(6),...
                                                    data(7),data(8),data(9),data(10),data(11),data(12));
                            fprintf(file_gps, '%8d%15.6f%15.6f%15.6f%15.6f%15.6f\r\n',...
                                                    data(1),data(13),data(14),data(15),data(16),data(17));
                        end
                        %------------------------------------------------------------------------------%
                    end % end k=1:nByte
                end % end while 1
                fclose(file_imu);
                fclose(file_gps);
            end % app.figure == 0
        end

        % Button pushed function: Collect_StopButton
        function Collect_StopButtonPushed(app, event)
            app.Collect_StartButton.Enable = 'on';
            app.collect = 0;
            send_cmd(app, 0);
        end

        % Button pushed function: Read_biasButton
        function Read_biasButtonPushed(app, event)
            if app.figure == 0
                % 1.Clear serial buffer
                clear_serial_buff(app);
                % 2.Initialize control variables
                pp = 0;
                % 3.Send command
                send_cmd(app, 81);
                % 4.Wait
                pause(0.4);
                % 5.Receive data and display
                while 1
                    nByte = app.s.BytesAvailable;
                    if nByte == 0
                        break;
                    end
                    app.BuffEditField.Value = nByte;
                    Rx = uint8(fread(app.s, nByte, 'uint8'));
                    for k=1:nByte
                        % Receive packet
                        [pp, flag] = receive_packet(app, pp, Rx(k));
                        if flag == 0
                            continue;
                        end
                        % Display
                        if app.packet(3) == 81 %code
                            for ki=4:4:(app.packet(2)-1)
                                eval(['app.Bias',num2str((ki-4)/4+1),'EditField.Value = double(typecast(app.packet(ki:(ki+3)),''single''));']);
                            end
                        end
                    end % end k=1:nByte
                end %end while 1
            end %end app.figure == 0
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            send_cmd(app, 40);
        end

        % Button pushed function: Read_att_filterButton
        function Read_att_filterButtonPushed(app, event)
            if app.figure == 0
                % 1.Clear serial buffer
                clear_serial_buff(app);
                % 2.Initialize control variables
                pp = 0;
                % 3.Send command
                send_cmd(app, 82);
                % 4.Wait
                pause(0.4);
                % 5.Receive data and display
                while 1
                    nByte = app.s.BytesAvailable;
                    if nByte == 0
                        break;
                    end
                    app.BuffEditField.Value = nByte;
                    Rx = uint8(fread(app.s, nByte, 'uint8'));
                    for k=1:nByte
                        % Receive packet
                        [pp, flag] = receive_packet(app, pp, Rx(k));
                        if flag == 0
                            continue;
                        end
                        % Display
                        if app.packet(3) == 82 %code
                            for ki=4:4:(app.packet(2)-1)
                                eval(['app.Att',num2str((ki-4)/4+1),'EditField.Value = double(typecast(app.packet(ki:(ki+3)),''single''));']);
                            end
                        end
                    end % end k=1:nByte
                end %end while 1
            end %end app.figure == 0
        end

        % Button pushed function: Write_biasButton
        function Write_biasButtonPushed(app, event)
            data = zeros(1,72, 'uint8');
            for ki = 1:4:length(data)
                eval(['data(ki:(ki+3)) = typecast(single(app.Bias',num2str((ki-1)/4+1),'EditField.Value),''uint8'');']);
            end
            send_cmd(app, 91, data);
        end

        % Button pushed function: Write_att_filterButton
        function Write_att_filterButtonPushed(app, event)
            data = zeros(1,68, 'uint8');
            for ki = 1:4:length(data)
                eval(['data(ki:(ki+3)) = typecast(single(app.Att',num2str((ki-1)/4+1),'EditField.Value),''uint8'');']);
            end
            send_cmd(app, 92, data);
        end

        % Button pushed function: Send_gps_llaButton
        function Send_gps_llaButtonPushed(app, event)
            send_cmd(app, 6);
        end

        % Button pushed function: Send_gps_xyzButton
        function Send_gps_xyzButtonPushed(app, event)
            send_cmd(app, 7);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.BaudEditFieldLabel = uilabel(app.UIFigure);
            app.BaudEditFieldLabel.Text = 'Baud';
            app.BaudEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudEditField.ValueDisplayFormat = '%.0f';
            app.BaudEditField.Value = 115200;
            app.FigureButton = uibutton(app.UIFigure, 'push');
            app.FigureButton.ButtonPushedFcn = createCallbackFcn(app, @FigureButtonPushed, true);
            app.FigureButton.Text = 'Figure';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.Lamp = uilamp(app.UIFigure);
            app.QueryButton = uibutton(app.UIFigure, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Text = 'Query';
            app.BuffEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuffEditField.ValueDisplayFormat = '%.0f';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FigureTab = uitab(app.TabGroup);
            app.FigureTab.Title = 'Figure';
            app.UIAxes1 = uiaxes(app.FigureTab);
            app.UIAxes2 = uiaxes(app.FigureTab);
            app.UIAxes3 = uiaxes(app.FigureTab);
            app.UIAxes4 = uiaxes(app.FigureTab);
            app.UIAxes5 = uiaxes(app.FigureTab);
            app.UIAxes6 = uiaxes(app.FigureTab);
            app.CmdTab = uitab(app.TabGroup);
            app.CmdTab.Title = 'Cmd';
            app.Send_timeButton = uibutton(app.CmdTab, 'push');
            app.Send_timeButton.ButtonPushedFcn = createCallbackFcn(app, @Send_timeButtonPushed, true);
            app.Send_timeButton.Enable = 'off';
            app.Send_timeButton.Text = 'Send_time';
            app.Send_attButton = uibutton(app.CmdTab, 'push');
            app.Send_attButton.ButtonPushedFcn = createCallbackFcn(app, @Send_attButtonPushed, true);
            app.Send_attButton.Enable = 'off';
            app.Send_attButton.Text = 'Send_att';
            app.Collect_StartButton = uibutton(app.CmdTab, 'push');
            app.Collect_StartButton.ButtonPushedFcn = createCallbackFcn(app, @Collect_StartButtonPushed, true);
            app.Collect_StartButton.Enable = 'off';
            app.Collect_StartButton.Text = 'Collect_Start';
            app.Collect_StopButton = uibutton(app.CmdTab, 'push');
            app.Collect_StopButton.ButtonPushedFcn = createCallbackFcn(app, @Collect_StopButtonPushed, true);
            app.Collect_StopButton.Enable = 'off';
            app.Collect_StopButton.Text = 'Collect_Stop';
            app.CntEditField = uieditfield(app.CmdTab, 'numeric');
            app.CntEditField.ValueDisplayFormat = '%.0f';
            app.CntEditField.Enable = 'off';
            app.RestartButton = uibutton(app.CmdTab, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Enable = 'off';
            app.RestartButton.Text = 'Restart';
            app.Send_gps_llaButton = uibutton(app.CmdTab, 'push');
            app.Send_gps_llaButton.ButtonPushedFcn = createCallbackFcn(app, @Send_gps_llaButtonPushed, true);
            app.Send_gps_llaButton.Enable = 'off';
            app.Send_gps_llaButton.Text = 'Send_gps_lla';
            app.Send_gps_xyzButton = uibutton(app.CmdTab, 'push');
            app.Send_gps_xyzButton.ButtonPushedFcn = createCallbackFcn(app, @Send_gps_xyzButtonPushed, true);
            app.Send_gps_xyzButton.Enable = 'off';
            app.Send_gps_xyzButton.Text = 'Send_gps_xyz';
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.SaveButton = uibutton(app.ParameterTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Read_biasButton = uibutton(app.ParameterTab, 'push');
            app.Read_biasButton.ButtonPushedFcn = createCallbackFcn(app, @Read_biasButtonPushed, true);
            app.Read_biasButton.Text = 'Read_bias';
            app.GxEditFieldLabel = uilabel(app.ParameterTab);
            app.GxEditFieldLabel.Text = 'Gx';
            app.Bias1EditField = uieditfield(app.ParameterTab, 'numeric');
            app.GyEditFieldLabel = uilabel(app.ParameterTab);
            app.GyEditFieldLabel.Text = 'Gy';
            app.Bias2EditField = uieditfield(app.ParameterTab, 'numeric');
            app.GzEditFieldLabel = uilabel(app.ParameterTab);
            app.GzEditFieldLabel.Text = 'Gz';
            app.Bias3EditField = uieditfield(app.ParameterTab, 'numeric');
            app.AxEditFieldLabel = uilabel(app.ParameterTab);
            app.AxEditFieldLabel.Text = 'Ax';
            app.Bias4EditField = uieditfield(app.ParameterTab, 'numeric');
            app.AyEditFieldLabel = uilabel(app.ParameterTab);
            app.AyEditFieldLabel.Text = 'Ay';
            app.Bias5EditField = uieditfield(app.ParameterTab, 'numeric');
            app.AzEditFieldLabel = uilabel(app.ParameterTab);
            app.AzEditFieldLabel.Text = 'Az';
            app.Bias6EditField = uieditfield(app.ParameterTab, 'numeric');
            app.MxEditFieldLabel = uilabel(app.ParameterTab);
            app.MxEditFieldLabel.Text = 'Mx';
            app.Bias7EditField = uieditfield(app.ParameterTab, 'numeric');
            app.MyEditFieldLabel = uilabel(app.ParameterTab);
            app.MyEditFieldLabel.Text = 'My';
            app.Bias8EditField = uieditfield(app.ParameterTab, 'numeric');
            app.MzEditFieldLabel = uilabel(app.ParameterTab);
            app.MzEditFieldLabel.Text = 'Mz';
            app.Bias9EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S11EditFieldLabel = uilabel(app.ParameterTab);
            app.S11EditFieldLabel.Text = 'S11';
            app.Bias10EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S12EditFieldLabel = uilabel(app.ParameterTab);
            app.S12EditFieldLabel.Text = 'S12';
            app.Bias11EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S13EditFieldLabel = uilabel(app.ParameterTab);
            app.S13EditFieldLabel.Text = 'S13';
            app.Bias12EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S21EditFieldLabel = uilabel(app.ParameterTab);
            app.S21EditFieldLabel.Text = 'S21';
            app.Bias13EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S22EditFieldLabel = uilabel(app.ParameterTab);
            app.S22EditFieldLabel.Text = 'S22';
            app.Bias14EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S23EditFieldLabel = uilabel(app.ParameterTab);
            app.S23EditFieldLabel.Text = 'S23';
            app.Bias15EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S31EditFieldLabel = uilabel(app.ParameterTab);
            app.S31EditFieldLabel.Text = 'S31';
            app.Bias16EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S32EditFieldLabel = uilabel(app.ParameterTab);
            app.S32EditFieldLabel.Text = 'S32';
            app.Bias17EditField = uieditfield(app.ParameterTab, 'numeric');
            app.S33EditFieldLabel = uilabel(app.ParameterTab);
            app.S33EditFieldLabel.Text = 'S33';
            app.Bias18EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Write_biasButton = uibutton(app.ParameterTab, 'push');
            app.Write_biasButton.ButtonPushedFcn = createCallbackFcn(app, @Write_biasButtonPushed, true);
            app.Write_biasButton.Text = 'Write_bias';
            app.P1Label = uilabel(app.ParameterTab);
            app.P1Label.Enable = 'off';
            app.P1Label.Text = 'P1';
            app.Att1EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att1EditField.Enable = 'off';
            app.P2Label = uilabel(app.ParameterTab);
            app.P2Label.Enable = 'off';
            app.P2Label.Text = 'P2';
            app.Att2EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att2EditField.Enable = 'off';
            app.P3Label = uilabel(app.ParameterTab);
            app.P3Label.Enable = 'off';
            app.P3Label.Text = 'P3';
            app.Att3EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att3EditField.Enable = 'off';
            app.P4Label = uilabel(app.ParameterTab);
            app.P4Label.Enable = 'off';
            app.P4Label.Text = 'P4';
            app.Att4EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att4EditField.Enable = 'off';
            app.P5Label = uilabel(app.ParameterTab);
            app.P5Label.Enable = 'off';
            app.P5Label.Text = 'P5';
            app.Att5EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att5EditField.Enable = 'off';
            app.P6Label = uilabel(app.ParameterTab);
            app.P6Label.Enable = 'off';
            app.P6Label.Text = 'P6';
            app.Att6EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att6EditField.Enable = 'off';
            app.P7Label = uilabel(app.ParameterTab);
            app.P7Label.Enable = 'off';
            app.P7Label.Text = 'P7';
            app.Att7EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att7EditField.Enable = 'off';
            app.Q1Label = uilabel(app.ParameterTab);
            app.Q1Label.Enable = 'off';
            app.Q1Label.Text = 'Q1';
            app.Att8EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att8EditField.Enable = 'off';
            app.Q2Label = uilabel(app.ParameterTab);
            app.Q2Label.Enable = 'off';
            app.Q2Label.Text = 'Q2';
            app.Att9EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att9EditField.Enable = 'off';
            app.Q3Label = uilabel(app.ParameterTab);
            app.Q3Label.Enable = 'off';
            app.Q3Label.Text = 'Q3';
            app.Att10EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att10EditField.Enable = 'off';
            app.Q4Label = uilabel(app.ParameterTab);
            app.Q4Label.Enable = 'off';
            app.Q4Label.Text = 'Q4';
            app.Att11EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att11EditField.Enable = 'off';
            app.Q5Label = uilabel(app.ParameterTab);
            app.Q5Label.Enable = 'off';
            app.Q5Label.Text = 'Q5';
            app.Att12EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att12EditField.Enable = 'off';
            app.Q6Label = uilabel(app.ParameterTab);
            app.Q6Label.Enable = 'off';
            app.Q6Label.Text = 'Q6';
            app.Att13EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att13EditField.Enable = 'off';
            app.R1Label = uilabel(app.ParameterTab);
            app.R1Label.Enable = 'off';
            app.R1Label.Text = 'R1';
            app.Att14EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att14EditField.Enable = 'off';
            app.R2Label = uilabel(app.ParameterTab);
            app.R2Label.Enable = 'off';
            app.R2Label.Text = 'R2';
            app.Att15EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att15EditField.Enable = 'off';
            app.R3Label = uilabel(app.ParameterTab);
            app.R3Label.Enable = 'off';
            app.R3Label.Text = 'R3';
            app.Att16EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att16EditField.Enable = 'off';
            app.R4Label = uilabel(app.ParameterTab);
            app.R4Label.Enable = 'off';
            app.R4Label.Text = 'R4';
            app.Att17EditField = uieditfield(app.ParameterTab, 'numeric');
            app.Att17EditField.Enable = 'off';
            app.Read_att_filterButton = uibutton(app.ParameterTab, 'push');
            app.Read_att_filterButton.ButtonPushedFcn = createCallbackFcn(app, @Read_att_filterButtonPushed, true);
            app.Read_att_filterButton.Enable = 'off';
            app.Read_att_filterButton.Text = 'Read_att_filter';
            app.Write_att_filterButton = uibutton(app.ParameterTab, 'push');
            app.Write_att_filterButton.ButtonPushedFcn = createCallbackFcn(app, @Write_att_filterButtonPushed, true);
            app.Write_att_filterButton.Enable = 'off';
            app.Write_att_filterButton.Text = 'Write_att_filter';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Send_imuButton = uibutton(app.UIFigure, 'push');
            app.Send_imuButton.ButtonPushedFcn = createCallbackFcn(app, @Send_imuButtonPushed, true);
            app.Send_imuButton.Text = 'Send_imu';
            app.Send_magButton = uibutton(app.UIFigure, 'push');
            app.Send_magButton.ButtonPushedFcn = createCallbackFcn(app, @Send_magButtonPushed, true);
            app.Send_magButton.Text = 'Send_mag';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NavUtility

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end